classdef La7Sobierajski_rkwapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        STOPButton               matlab.ui.control.Button
        WpiszcEditFieldLabel     matlab.ui.control.Label
        WpiszcEditField          matlab.ui.control.NumericEditField
        X1EditFieldLabel         matlab.ui.control.Label
        X1EditField              matlab.ui.control.NumericEditField
        X2Label                  matlab.ui.control.Label
        X2EditField              matlab.ui.control.NumericEditField
        xwEditFieldLabel         matlab.ui.control.Label
        xwEditField              matlab.ui.control.NumericEditField
        ywEditFieldLabel         matlab.ui.control.Label
        ywEditField              matlab.ui.control.NumericEditField
        WpiszaEditFieldLabel     matlab.ui.control.Label
        WpiszaEditField          matlab.ui.control.NumericEditField
        WpiszbEditFieldLabel     matlab.ui.control.Label
        WpiszbEditField          matlab.ui.control.NumericEditField
        WykresiobliczeniaButton  matlab.ui.control.Button
    end


    methods (Access = private)
    
            
function [X1,X2,xw,yw]=rkwrysapp(app,a,b,c)
%Funkcja rkwrysapp() rysuje wykres dla x w przedziale od xmin do xmax
xmin=-5; xmax=5; dx =0.1;
x=xmin:dx:xmax; % przedzial zmiennosci x
X1=[]; X2=[]; xw=[]; yw=[];
if  a==0 
  warndlg('"a" musi byc rozne od zera!');
else
  d=b^2-4*a*c;%Wyroznik rownania kwadratowego
  if d>=0
     X1=(-b-sqrt(d))/(2*a);   X2=(-b+sqrt(d))/(2*a);
  else
     X1=inf;  X2=inf; % brak rozwiazan rzeczywistych
  end % if d>=0
  %Obliczenie wspolrzednych wierzcholka funkcji
  xw=(-b/(2*a)); yw=(-d/(4*a));

% WYKRES paraboli
 osx0=x*0; % os x
 y=a*x.*x+b.*x+c;
 plot(app.UIAxes,x,y,'-b',x,osx0,'-r',osx0,y,'-r');
 end %if a==0   warndlg('"a" musi byc rozne od zera!'); else
end


    end


    methods (Access = private)

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: WpiszcEditField
        function WpiszcEditFieldValueChanged(app, event)
            value = app.WpiszcEditField.Value;
            
        end

        % Callback function
        function WybierzcSliderValueChanged(app, event)

        end

        % Value changed function: X1EditField
        function X1EditFieldValueChanged(app, event)
            value = app.X1EditField.Value;
            
        end

        % Value changed function: X2EditField
        function X2EditFieldValueChanged(app, event)
            value = app.X2EditField.Value;
            
        end

        % Value changed function: WpiszbEditField
        function WpiszbEditFieldValueChanged(app, event)
            value = app.WpiszbEditField.Value;
            
        end

        % Value changed function: WpiszaEditField
        function WpiszaEditFieldValueChanged(app, event)
            value = app.WpiszaEditField.Value;
            
        end

        % Button pushed function: WykresiobliczeniaButton
        function WykresiobliczeniaButtonPushed(app, event)
            a=app.WpiszaEditField.Value; 
            b=app.WpiszbEditField.Value;
            c=app.WpiszcEditField.Value;
            % wykres i obliczenia
            [X1,X2,xw,yw]=rkwrysapp(app,a,b,c);
            % wyprowadzanie wynikow do okienek edycyjnych
            app.X1EditField.Value=X1;
            app.X2EditField.Value=X2;
            app.xwEditField.Value=xw;
            app.ywEditField.Value=yw;

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'y=a*x^2+b*x+c - analiza - Sobierajski')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.WpiszcEditFieldLabel = uilabel(app.UIFigure);
            app.WpiszcEditFieldLabel.Text = 'Wpisz c';
            app.WpiszcEditField = uieditfield(app.UIFigure, 'numeric');
            app.WpiszcEditField.ValueChangedFcn = createCallbackFcn(app, @WpiszcEditFieldValueChanged, true);
            app.X1EditFieldLabel = uilabel(app.UIFigure);
            app.X1EditFieldLabel.Text = 'X1=';
            app.X1EditField = uieditfield(app.UIFigure, 'numeric');
            app.X1EditField.ValueChangedFcn = createCallbackFcn(app, @X1EditFieldValueChanged, true);
            app.X2Label = uilabel(app.UIFigure);
            app.X2Label.Text = 'X2=';
            app.X2EditField = uieditfield(app.UIFigure, 'numeric');
            app.X2EditField.ValueChangedFcn = createCallbackFcn(app, @X2EditFieldValueChanged, true);
            app.xwEditFieldLabel = uilabel(app.UIFigure);
            app.xwEditFieldLabel.Text = 'xw=';
            app.xwEditField = uieditfield(app.UIFigure, 'numeric');
            app.ywEditFieldLabel = uilabel(app.UIFigure);
            app.ywEditFieldLabel.Text = 'yw=';
            app.ywEditField = uieditfield(app.UIFigure, 'numeric');
            app.WpiszaEditFieldLabel = uilabel(app.UIFigure);
            app.WpiszaEditFieldLabel.Text = 'Wpisz a';
            app.WpiszaEditField = uieditfield(app.UIFigure, 'numeric');
            app.WpiszaEditField.ValueChangedFcn = createCallbackFcn(app, @WpiszaEditFieldValueChanged, true);
            app.WpiszbEditFieldLabel = uilabel(app.UIFigure);
            app.WpiszbEditFieldLabel.Text = 'Wpisz b';
            app.WpiszbEditField = uieditfield(app.UIFigure, 'numeric');
            app.WpiszbEditField.ValueChangedFcn = createCallbackFcn(app, @WpiszbEditFieldValueChanged, true);
            app.WykresiobliczeniaButton = uibutton(app.UIFigure, 'push');
            app.WykresiobliczeniaButton.ButtonPushedFcn = createCallbackFcn(app, @WykresiobliczeniaButtonPushed, true);
            app.WykresiobliczeniaButton.Text = 'Wykres i obliczenia';
        end
    end

    methods (Access = public)

        % Construct app
        function app = La7Sobierajski_rkwapp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end