classdef orlcapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        RSliderLabel         matlab.ui.control.Label
        RSlider              matlab.ui.control.Slider
        XLomEditFieldLabel   matlab.ui.control.Label
        XLomEditField        matlab.ui.control.NumericEditField
        XComEditFieldLabel   matlab.ui.control.Label
        XComEditField        matlab.ui.control.NumericEditField
        RomEditField_2Label  matlab.ui.control.Label
        RomEditField         matlab.ui.control.NumericEditField
        UIAxes2              matlab.ui.control.UIAxes
        STOPButton           matlab.ui.control.Button
        UIAxes3              matlab.ui.control.UIAxes
    end


    methods (Access = private)
    
function orlcfunapp(app,R,XL,XC)
% Funkcja orlcfunapp(app,R)
% wykorzystuje appdesigner
% symuluje stan nieustalony w obwodzie szeregowym RLC przy zmiennym R
% rownania rozniczkowe definiuje funkcja: dxdt=orlcdx(t,x)
% zagniezdzona w funkcji nested_ode(app,tzakres,x0,R)
w=100*pi; % pulsacja pradu
% parametry obwodu
psiE=60; %stopnie
E = 100; % V
% R=wartosc z suwaka przekazana  , om
L = XL/w; % XL w omach, L w H
Co=1/w/XC; % XC w omach, C w F
Em=sqrt(2)*E; % amplituda sem
psi=psiE/180*pi;
t0=0; tk=0.1; dt=0.001; % czas poczatku i konca analizy w s
tp=t0:dt:tk; % przedzial analizy
x0=[0 0]; % warunek poczatkowy
% wywolanie funkcji zagniezdzonej 
[t,x]=nested_ode(app,tp,x0,R,L,Co,Em,psi);
%
i=x(:,1); % przebieg pradu
uC=x(:,2); % przebieg napiecia na kondensatorze
e=Em*sin(100*pi*t+psi); % wymuszenie sem e
uL=e-R*i-uC; % przebieg napiecia na cewce
uLuC=uL+uC; % suma napiec na cewce i kondensatorze
plot(app.UIAxes,t,uLuC,'k-',t,e,'b:','LineWidth',1.5);
pause(5);
plot(app.UIAxes2,t,i,'k-');
pause(5);
plot(app.UIAxes3,t,uC,'r-','LineWidth',1.5);
pause(5);
end

function [t,x]=nested_ode(app,tp,x0,R,L,Co,Em,psi);
    A = [-R/L  -1/L 
         1/Co     0];
    B = [1/L; 0];
    C = [1 0]; D=[0];
    

[t,x]=ode23(@orlcdx,tp,x0); 
    function dxdt=orlcdx(t,x)
    e=Em*sin(100*pi*t+psi); % wymuszenie
    dxdt=A*x+B*e; % rownanie stanu
    end
end


    end


    methods (Access = private)

        % Value changed function: RSlider
        function RSliderValueChanged(app, event)
            R = app.RSlider.Value;
            R
            XL=80; %om
            XC=80; %om
            app.XLomEditField.Value=XL;
            app.XComEditField.Value=XC;
            app.RomEditField.Value=R;           
            orlcfunapp(app,R,XL,XC);
        end

        % Value changed function: RomEditField
        function RomEditFieldValueChanged(app, event)
            value = app.RomEditField.Value;
            
        end

        % Value changed function: XComEditField
        function XComEditFieldValueChanged(app, event)
            value = app.XComEditField.Value;
            
        end

        % Value changed function: XLomEditField
        function XLomEditFieldValueChanged(app, event)
            value = app.XLomEditField.Value;
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Nap. uL(t)+uC(t),e(t)')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'uL+uC,[V]')
            app.RSliderLabel = uilabel(app.UIFigure);
            app.RSliderLabel.Text = 'R';
            app.RSlider = uislider(app.UIFigure);
            app.RSlider.ValueChangedFcn = createCallbackFcn(app, @RSliderValueChanged, true);
            app.XLomEditFieldLabel = uilabel(app.UIFigure);
            app.XLomEditFieldLabel.Text = 'XL,om';
            app.XLomEditField = uieditfield(app.UIFigure, 'numeric');
            app.XLomEditField.ValueChangedFcn = createCallbackFcn(app, @XLomEditFieldValueChanged, true);
            app.XComEditFieldLabel = uilabel(app.UIFigure);
            app.XComEditFieldLabel.Text = 'XC,om';
            app.XComEditField = uieditfield(app.UIFigure, 'numeric');
            app.XComEditField.ValueChangedFcn = createCallbackFcn(app, @XComEditFieldValueChanged, true);
            app.RomEditField_2Label = uilabel(app.UIFigure);
            app.RomEditField_2Label.Text = 'R,om';
            app.RomEditField = uieditfield(app.UIFigure, 'numeric');
            app.RomEditField.ValueChangedFcn = createCallbackFcn(app, @RomEditFieldValueChanged, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Prad i(t)')
            xlabel(app.UIAxes2, 't[s]')
            ylabel(app.UIAxes2, 'i[A]')
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'uC(t)')
            xlabel(app.UIAxes3, 't')
            ylabel(app.UIAxes3, 'uC[V]')
        end
    end

    methods (Access = public)

        % Construct app
        function app = orlcapp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end